classdef code_correcteur < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CheckconnectionButton      matlab.ui.control.Button
        YourdataEditFieldLabel     matlab.ui.control.Label
        YourdataEditField          matlab.ui.control.EditField
        DataencodedEditFieldLabel  matlab.ui.control.Label
        DataencodedEditField       matlab.ui.control.EditField
        DatasendEditFieldLabel     matlab.ui.control.Label
        DatasendEditField          matlab.ui.control.EditField
        DatareceiveEditFieldLabel  matlab.ui.control.Label
        DatareceiveEditField       matlab.ui.control.EditField
        DatadecodedEditFieldLabel  matlab.ui.control.Label
        DatadecodedEditField       matlab.ui.control.EditField
        DataendEditFieldLabel      matlab.ui.control.Label
        DataendEditField           matlab.ui.control.EditField
        DoexchangeButton           matlab.ui.control.Button
        CorrectorcodeButtonGroup   matlab.ui.container.ButtonGroup
        HammingButton              matlab.ui.control.RadioButton
        BCHButton                  matlab.ui.control.RadioButton
        codepolynomialeButton      matlab.ui.control.RadioButton
        ConfigurationsLabel        matlab.ui.control.Label
        ExchangeLabel              matlab.ui.control.Label
        InformationTextArea        matlab.ui.control.TextArea
        SerialportListBoxLabel     matlab.ui.control.Label
        SerialportListBox          matlab.ui.control.ListBox
        RefreshlistSwitchLabel     matlab.ui.control.Label
        RefreshlistSwitch          matlab.ui.control.ToggleSwitch
        CloseconnectionButton      matlab.ui.control.Button
        ChangethedatabitLabel      matlab.ui.control.Label
        ValidateButton             matlab.ui.control.Button
        CreateErrorsLabel          matlab.ui.control.Label
        CreateErrorsEditField      matlab.ui.control.EditField
    end

    
    properties (Access = private)
        serial_com = 0; % variable for serial communication
        selected_port = 0; % port serie select from interface
        codeur = 0;
        textEdit_data ='';
        % Transmitter
        tr_data_to_send_dec = []; % Vector of coded message in decimal
        tr_nb_padding_added = 0; % Number of padding's zeros used in Tx
        % Receiver
        rv_data_to_decode_str = ''; % Concatenation of all datas received
        rv_nb_packet_received = 0; % Count the received packets for a trame 
        rv_num_trame = 0; % In first byte of a trame : id of the trame
        rv_total_trame = 0; % In first byte of a trame : number total of trame
        rv_nb_padding_added = 0; % In last byte of a trame : number of padding's zeros add
        rv_corrector_code_used = 0; % In last byte of a trame : id of corrector method used
    end
    
    properties (Access = public)
        DECODE_CONSTANT = 0; % To select the decoded way
        CODE_CONSTANT = 1; % To select the coded way
    end
    
    methods (Access = private)
        
        function results = clear_interface(app)
            app.YourdataEditField.Value = '';
            app.DataencodedEditField.Value = '';
            app.DatasendEditField.Value = '';
            app.DatareceiveEditField.Value = '';
            app.DatadecodedEditField.Value = '';
            app.DataendEditField.Value = '';
            app.serial_com.BytesToOutput
            while (app.serial_com.BytesToOutput ~= 0)
                fwrite(app.serial_com);
            end
            app.serial_com.BytesAvailable
            while (app.serial_com.BytesAvailable ~= 0)
                fread(app.serial_com);
            end
        end
        
        function results = TRANSMITTER_finish_send(app)    
            app.ChangethedatabitLabel.Visible = 'off';
            app.CreateErrorsEditField.Visible = 'off';
            app.CreateErrorsLabel.Visible = 'off';
            app.ValidateButton.Visible = 'off';
            
            nb_octet_padding_added = 0;
            % cut the data into trame of 4 bytes
            while (mod(size(app.tr_data_to_send_dec,2),4))
                app.tr_data_to_send_dec = [app.tr_data_to_send_dec 0];
                nb_octet_padding_added = nb_octet_padding_added+1
            end
            nb_total = size(app.tr_data_to_send_dec,2)/4;
            tr_trame_to_send = zeros(4,nb_total);
            col = 1;
            for i = 1:1:nb_total
                compt_line = 1+(4*(i-1));
                next_line = mod(compt_line+3,size(app.tr_data_to_send_dec,2));
                
                if (next_line < compt_line+3)
                    tr_trame_to_send(:,i) = app.tr_data_to_send_dec(compt_line:end)'
                else
                    tr_trame_to_send(:,i) = app.tr_data_to_send_dec(compt_line:compt_line+3)';
                end
            end
            
            set(app.DatasendEditField,"Value",char(app.tr_data_to_send_dec));
            
%             % Example with word 'plop'
%             % preamble trame 1/2
%             fwrite(app.serial_com,char(18));
%             % Envoi des 4 packets trame 1
%             fwrite(app.serial_com,char(app.tr_data_to_send_dec(1:4)));
%             % preamble trame 2/2
%             fwrite(app.serial_com,char(34));
%             % last trame donc envoi padding et codeur used (1 2 3)
%             fwrite(app.serial_com,char(40));
%             % Envoi des 4 packets trame 2
%             fwrite(app.serial_com,[char(app.tr_data_to_send_dec(5:end))]);

            
            nb_trame = 1;
            
            for i = 1:1:nb_total
                % send preamble
                preamble_trame = nb_trame*2^4+nb_total
                fwrite(app.serial_com,char(preamble_trame));
                
                % if last trame send codeur and padding bits
                if (nb_trame == nb_total)
                    codeur_padding_to_send = app.codeur*2^5+((nb_octet_padding_added*8)+app.tr_nb_padding_added)
                    fwrite(app.serial_com,char(codeur_padding_to_send));
                end
                
                % send packets
                compt_line = 1+(4*(i-1));
                fwrite(app.serial_com,char(app.tr_data_to_send_dec(compt_line:compt_line+3)));
                
                nb_trame = nb_trame+1;
            end            
        end
        
        function results = RECEIVER_decode(app)
            fprintf('Receiver decoder\n')
            % decode received datas
            x= reshape(dec2bin(app.rv_data_to_decode_str, 8)', 1, [])  - '0'
            app.rv_data_to_decode_str = '';
            
            x = x(1:end-app.rv_nb_padding_added)
            
            % select the right corrector used to decode data
            if (app.rv_corrector_code_used == 1)
                decData = hamming_function(x,app.DECODE_CONSTANT);
            elseif (app.rv_corrector_code_used == 2)
                decData = bch_function(x,app.DECODE_CONSTANT)
            elseif (app.rv_corrector_code_used == 3)
                decData = polynomiale_function(x,app.DECODE_CONSTANT);
            end
            app.rv_corrector_code_used = 0;
            
            set(app.DatadecodedEditField,"Value",num2str(decData));
            pause(0.001);
            
            % Reconvert to char
            char_data='';
            for j = (0:length(decData)/8-1)
                char_data = [char_data,sum(decData(1+(j*8):8+(j*8)).*2.^[7:-1:0])];
            end
            app.DataendEditField.Value = char_data;
%             
%             % check error and BER
%             % numerr = biterr(data_to_decode_string,decData);
%             % set(app.DataendEditField,"Value",numerr);
        end
        
      function results = RECEIVER_NewBytesFromSerial(app, src, event)
            fprintf('ok callback new bytes\n');
            % Read data receive
            [one_byte_received_dec, nb_charac] = fread(app.serial_com,1);

            % if app.rv_num_packet null -> first byte of trame received
            if (app.rv_num_trame == 0)
                app.rv_num_trame = fix(one_byte_received_dec/2^4);
                app.rv_total_trame = fix(one_byte_received_dec-(app.rv_num_trame*2^4));
                fprintf('New trame : %d / %d\n', app.rv_num_trame, app.rv_total_trame)
            % if last trame received : get the padding and codeur used
            elseif ((app.rv_num_trame == app.rv_total_trame) & (app.rv_corrector_code_used == 0))
                 fprintf('Last trame of the exchange\n')
                 app.rv_corrector_code_used = fix(one_byte_received_dec/2^5);
                 app.rv_nb_padding_added = fix(one_byte_received_dec-(app.rv_corrector_code_used*2^5));
                 fprintf('codeur used=%d - nb padding bits=%d\n', app.rv_corrector_code_used, app.rv_nb_padding_added)
            else
                app.rv_data_to_decode_str = [app.rv_data_to_decode_str, char([one_byte_received_dec.'])];
                set(app.DatareceiveEditField,"Value",app.rv_data_to_decode_str);
                app.rv_nb_packet_received = app.rv_nb_packet_received+1;
                pause(0.001);
            end

            % Trame of 32 bits received
            if (app.rv_nb_packet_received == 4)
                fprintf('32 bits recu\n');
                app.rv_nb_packet_received = 0;                
                
                % Last packet of last trame received
                if (app.rv_num_trame == app.rv_total_trame)   
                    fprintf('\nCall the encoder\n')
                    app.rv_num_trame = 0;
                    app.rv_total_trame = 0;
                    app.RECEIVER_decode();
                end
                app.rv_num_trame = 0;
                

            end
        end
    end
    
    methods (Access = public)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            all_ports = ["--", seriallist];
            % Changement des valeurs par défaut
            app.SerialportListBox.Items = all_ports;
            set(app.DoexchangeButton, "enable", "off");
            set(app.CloseconnectionButton, "Visible", "off");
        end

        % Button pushed function: CheckconnectionButton
        function CheckconnectionButtonPushed(app, event)
            try
                app.serial_com = serial(app.selected_port);
                app.serial_com.BytesAvailableFcn = {@app.RECEIVER_NewBytesFromSerial};
                app.serial_com.BytesAvailableFcnMode = 'byte';
%                 app.serial_com.Terminator = 'CR';
                app.serial_com.BytesAvailableFcnCount = 1;
                
                fopen(app.serial_com);
                connect_test_response = ["the port is ", get(app.serial_com,"Status")];
                set(app.DoexchangeButton, "enable", "on");
                set(app.CheckconnectionButton, "enable", "off");
                set(app.CloseconnectionButton, "Visible", "on");
            catch ME
                connect_test_response = "Can't connect, try instrfind command to see if port already used";
            end    
              
            app.textEdit_data = [app.textEdit_data, connect_test_response];
            set(app.InformationTextArea,"Value",app.textEdit_data);
        end

        % Value changed function: SerialportListBox
        function SerialportListBoxValueChanged(app, event)
            app.selected_port = get(app.SerialportListBox,"Value");
            app.textEdit_data = [app.textEdit_data, app.selected_port];
            set(app.InformationTextArea,"Value",app.textEdit_data);
        end

        % Button pushed function: DoexchangeButton
        function DoexchangeButtonPushed(app, event)
            data_to_code = get(app.YourdataEditField,"Value");
            app.clear_interface()
            app.YourdataEditField.Value = data_to_code;
            if (strcmp(data_to_code,''))
                app.textEdit_data = [app.textEdit_data, "please enter data noob"];
                set(app.InformationTextArea,"Value",app.textEdit_data);
                pause(0.001);
            else
            
            % Call external script fonction of the corretor Code Selection
            % chosen
            code_correcteur_selectionne = get(get(app.CorrectorcodeButtonGroup, "SelectedObject"),"Text");
            app.textEdit_data = [app.textEdit_data, "code selected : ", code_correcteur_selectionne];
            set(app.InformationTextArea,"Value",app.textEdit_data);
            
            % Go to binary to change some bits
            x= reshape(dec2bin(data_to_code, 8)', 1, [])  - '0';
            % don't work...
%             x = str2double(dec2bin(data_to_code,8));

            if (code_correcteur_selectionne == "Hamming")
                app.codeur = 1;
                data_encoded = hamming_function(x,app.CODE_CONSTANT);
            elseif (code_correcteur_selectionne == "BCH")
                app.codeur = 2;
                data_encoded = bch_function(x,app.CODE_CONSTANT);
            elseif (code_correcteur_selectionne == "code polynomiale")
                app.codeur = 3;
                data_encoded = polynomiale_function(x,app.CODE_CONSTANT);
            end
            
            % Display coded data after coding
            set(app.DataencodedEditField,"Value",num2str(data_encoded));
            pause(0.001);
            
            % Open the change bit parameters
            app.ChangethedatabitLabel.Visible = 'on';
            app.CreateErrorsEditField.Visible = 'on';
            app.CreateErrorsLabel.Visible = 'on';
            app.ValidateButton.Visible = 'on';
            
            % Display the bits in editfield and call callback to print
            % value in label
            set(app.CreateErrorsEditField, "Value", num2str(data_encoded));
            CreateErrorsEditFieldValueChanged(app, event);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try 
                CloseconnectionButtonPushed(app, event);
                % fclose(app.serial_com);
            catch ME
                CloseconnectionButtonPushed(app, event);
                % fclose(app.serial_com);
            end
            delete(app);
        end

        % Value changed function: RefreshlistSwitch
        function RefreshlistSwitchValueChanged(app, event)
            all_ports = ["--", seriallist];
            % Changement des valeurs par défaut
            app.SerialportListBox.Items = all_ports;
        end

        % Button pushed function: CloseconnectionButton
        function CloseconnectionButtonPushed(app, event)
            try 
                app.clear_interface();
                fclose(app.serial_com);
                
                set(app.DoexchangeButton, "enable", "off");
                set(app.CloseconnectionButton, "Visible", "off");
                close_test_response = ["the port is ", get(app.serial_com,"Status")];
                set(app.CheckconnectionButton, "enable", "on");
            catch ME
                close_test_response = ["Can't close port ! "];
            end
            app.textEdit_data = [app.textEdit_data, close_test_response];
            set(app.InformationTextArea,"Value",app.textEdit_data);
        end

        % Button pushed function: ValidateButton
        function ValidateButtonPushed(app, event)
            TRANSMITTER_finish_send(app);
        end

        % Value changed function: CreateErrorsEditField
        function CreateErrorsEditFieldValueChanged(app, event)
           my_data_field = app.CreateErrorsEditField.Value;
           
           app.tr_nb_padding_added = 0;
           % Get the string instead of bit
           my_data_field_num = str2num(my_data_field)
           while (mod(length(my_data_field_num),8) ~= 0)
               app.tr_nb_padding_added = app.tr_nb_padding_added +1;
               my_data_field_num = [my_data_field_num,0];
           end
           fprintf('%d bits added in order to get octet from codeur output', app.tr_nb_padding_added)
           
            % Reconvert to char
            char_data=[];
            for j = (0:length(my_data_field_num)/8-1)
                char_data = [char_data,sum(my_data_field_num(1+(j*8):8+(j*8)).*2.^[7:-1:0])];
            end
%             char_data = char_data(2:end);
            
            app.tr_data_to_send_dec = char_data;
            % Display char result to label
            app.CreateErrorsLabel.Text = char(char_data);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CheckconnectionButton = uibutton(app.UIFigure, 'push');
            app.CheckconnectionButton.ButtonPushedFcn = createCallbackFcn(app, @CheckconnectionButtonPushed, true);
            app.CheckconnectionButton.Text = 'Check connection';
            app.YourdataEditFieldLabel = uilabel(app.UIFigure);
            app.YourdataEditFieldLabel.Text = 'Your data';
            app.YourdataEditField = uieditfield(app.UIFigure, 'text');
            app.DataencodedEditFieldLabel = uilabel(app.UIFigure);
            app.DataencodedEditFieldLabel.Text = 'Data encoded';
            app.DataencodedEditField = uieditfield(app.UIFigure, 'text');
            app.DatasendEditFieldLabel = uilabel(app.UIFigure);
            app.DatasendEditFieldLabel.Text = 'Data send';
            app.DatasendEditField = uieditfield(app.UIFigure, 'text');
            app.DatareceiveEditFieldLabel = uilabel(app.UIFigure);
            app.DatareceiveEditFieldLabel.Text = 'Data receive';
            app.DatareceiveEditField = uieditfield(app.UIFigure, 'text');
            app.DatadecodedEditFieldLabel = uilabel(app.UIFigure);
            app.DatadecodedEditFieldLabel.Text = 'Data decoded';
            app.DatadecodedEditField = uieditfield(app.UIFigure, 'text');
            app.DataendEditFieldLabel = uilabel(app.UIFigure);
            app.DataendEditFieldLabel.Text = 'Data end';
            app.DataendEditField = uieditfield(app.UIFigure, 'text');
            app.DoexchangeButton = uibutton(app.UIFigure, 'push');
            app.DoexchangeButton.ButtonPushedFcn = createCallbackFcn(app, @DoexchangeButtonPushed, true);
            app.DoexchangeButton.Text = 'Do exchange';
            app.CorrectorcodeButtonGroup = uibuttongroup(app.UIFigure);
            app.CorrectorcodeButtonGroup.Title = 'Corrector code';
            app.HammingButton = uiradiobutton(app.CorrectorcodeButtonGroup);
            app.HammingButton.Text = 'Hamming';
            app.HammingButton.Value = true;
            app.BCHButton = uiradiobutton(app.CorrectorcodeButtonGroup);
            app.BCHButton.Text = 'BCH';
            app.codepolynomialeButton = uiradiobutton(app.CorrectorcodeButtonGroup);
            app.codepolynomialeButton.Text = 'code polynomiale';
            app.ConfigurationsLabel = uilabel(app.UIFigure);
            app.ConfigurationsLabel.Text = 'Configurations';
            app.ExchangeLabel = uilabel(app.UIFigure);
            app.ExchangeLabel.Text = 'Exchange';
            app.InformationTextArea = uitextarea(app.UIFigure);
            app.SerialportListBoxLabel = uilabel(app.UIFigure);
            app.SerialportListBoxLabel.Text = 'Serial port';
            app.SerialportListBox = uilistbox(app.UIFigure);
            app.SerialportListBox.ValueChangedFcn = createCallbackFcn(app, @SerialportListBoxValueChanged, true);
            app.RefreshlistSwitchLabel = uilabel(app.UIFigure);
            app.RefreshlistSwitchLabel.Text = 'Refresh list';
            app.RefreshlistSwitch = uiswitch(app.UIFigure, 'toggle');
            app.RefreshlistSwitch.ValueChangedFcn = createCallbackFcn(app, @RefreshlistSwitchValueChanged, true);
            app.CloseconnectionButton = uibutton(app.UIFigure, 'push');
            app.CloseconnectionButton.ButtonPushedFcn = createCallbackFcn(app, @CloseconnectionButtonPushed, true);
            app.CloseconnectionButton.Text = 'Close connection';
            app.ChangethedatabitLabel = uilabel(app.UIFigure);
            app.ChangethedatabitLabel.Visible = 'off';
            app.ChangethedatabitLabel.Text = 'Change the data bit';
            app.ValidateButton = uibutton(app.UIFigure, 'push');
            app.ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateButtonPushed, true);
            app.ValidateButton.Visible = 'off';
            app.ValidateButton.Text = 'Validate';
            app.CreateErrorsLabel = uilabel(app.UIFigure);
            app.CreateErrorsLabel.Visible = 'off';
            app.CreateErrorsEditField = uieditfield(app.UIFigure, 'text');
            app.CreateErrorsEditField.ValueChangedFcn = createCallbackFcn(app, @CreateErrorsEditFieldValueChanged, true);
            app.CreateErrorsEditField.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = code_correcteur

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end